classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        DrawTrajectoryButton    matlab.ui.control.Button
        FollowButton            matlab.ui.control.Button
        HomeButton              matlab.ui.control.Button
        Joint6Slider            matlab.ui.control.Slider
        Joint6SliderLabel       matlab.ui.control.Label
        Joint5Slider            matlab.ui.control.Slider
        Joint5SliderLabel       matlab.ui.control.Label
        Joint4Slider            matlab.ui.control.Slider
        Joint4SliderLabel       matlab.ui.control.Label
        Joint3Slider            matlab.ui.control.Slider
        Joint3SliderLabel       matlab.ui.control.Label
        Joint2Slider            matlab.ui.control.Slider
        Joint2SliderLabel       matlab.ui.control.Label
        Joint1Slider            matlab.ui.control.Slider
        Joint1SliderLabel       matlab.ui.control.Label
        InverseButton           matlab.ui.control.Button
        ForwardButton           matlab.ui.control.Button
        q6EditField             matlab.ui.control.NumericEditField
        q6EditFieldLabel        matlab.ui.control.Label
        q5EditField             matlab.ui.control.NumericEditField
        q5EditFieldLabel        matlab.ui.control.Label
        q4EditField             matlab.ui.control.NumericEditField
        q4EditFieldLabel        matlab.ui.control.Label
        FowardKinematicsLabel   matlab.ui.control.Label
        q3EditField             matlab.ui.control.NumericEditField
        q3EditFieldLabel        matlab.ui.control.Label
        q2EditField             matlab.ui.control.NumericEditField
        q2EditFieldLabel        matlab.ui.control.Label
        q1EditField             matlab.ui.control.NumericEditField
        q1EditFieldLabel        matlab.ui.control.Label
        pzEditField             matlab.ui.control.NumericEditField
        pzEditFieldLabel        matlab.ui.control.Label
        pyEditField             matlab.ui.control.NumericEditField
        pyEditFieldLabel        matlab.ui.control.Label
        pxEditField             matlab.ui.control.NumericEditField
        pxEditFieldLabel        matlab.ui.control.Label
        InverseKinematicsLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            th1=app.q1EditField.Value;
            th2=app.q2EditField.Value;
            th3=app.q3EditField.Value;
            th4=app.q4EditField.Value;
            th5=app.q5EditField.Value;
            th6=app.q6EditField.Value;
            qdeg = [th1, th2, th3, th4, th5, th6];
            q=qdeg.*(pi/180);
            
            ModelName = 'RobotPUMA560_3_wall';
            set_param([ModelName,'/Slider Gain'],'Gain',num2str(q(1,1)*(180/pi)));
            set_param([ModelName,'/Slider Gain1'],'Gain',num2str(q(1,2)*(180/pi)));
            set_param([ModelName,'/Slider Gain2'],'Gain',num2str(q(1,3)*(180/pi)));
            set_param([ModelName,'/Slider Gain3'],'Gain',num2str(90+q(1,4)*(180/pi)));
            set_param([ModelName,'/Slider Gain4'],'Gain',num2str(q(1,5)*(180/pi)));
            set_param([ModelName,'/Slider Gain5'],'Gain',num2str(q(1,6)*(180/pi)));

            import ETS3.*;
            %mdl_puma560;
        %% Building puma560 with DH parameter
        l(1) = Link([0,0,0,pi/2,0]);
        l(2) = Link([0,-0.15,0.4318,0,0]);
        l(3) = Link([0,0,0.0203,-pi/2,0]);
        l(4) = Link([0,0.4318,0,pi/2,0]);
        l(5) = Link([0,0,0,-pi/2,0]);
        l(6) = Link([0,0,0,0,0]);
        p560 = SerialLink(l);
            T=p560.fkine(q);
            figure(1);
            p560.plot(q);

            app.pxEditField.Value=T.t(1,1)
            app.pyEditField.Value=T.t(2,1)
            app.pzEditField.Value=T.t(3,1)
            %T=evalin('base','Trans');  %read Trans from workspace as T
            %assignin('base','Trans',T)  %put T into workspace as Trans

%            app.EditField1.Value=evalin('base','myvar1');

        end

        % Button pushed function: InverseButton
        function InverseButtonPushed(app, event)
            pos_x=app.pxEditField.Value;
            pos_y=app.pyEditField.Value;
            pos_z=app.pzEditField.Value;
            
            pos = [pos_x, pos_y, pos_z];

            import ETS3.*;
            %mdl_puma560;
        %% Building puma560 with DH parameter
        l(1) = Link([0,0,0,pi/2,0]);
        l(2) = Link([0,-0.15,0.4318,0,0]);
        l(3) = Link([0,0,0.0203,-pi/2,0]);
        l(4) = Link([0,0.4318,0,pi/2,0]);
        l(5) = Link([0,0,0,-pi/2,0]);
        l(6) = Link([0,0,0,0,0]);
        p560 = SerialLink(l);
            Q=p560.ikine6s(pos);
            figure(1);
            p560.plot(Q)
            
            r(1,1)=Q(1,1)*(180/pi);
            r(1,2)=Q(1,2)*(180/pi);
            r(1,3)=Q(1,3)*(180/pi);
            r(1,4)=Q(1,4)*(180/pi);
            r(1,5)=Q(1,5)*(180/pi);
            r(1,6)=Q(1,6)*(180/pi);
                     
        for i=1:6
            if r(1,i)>180
               r(1,i)=r(1,i)-360;
            end
            if r(1,i)<(-180)
               r(1,i)=r(1,i)+360;
            end
        end
        
            set(app.q1EditField,'value',r(1,1));
            set(app.q2EditField,'value',r(1,2));
            set(app.q3EditField,'value',r(1,3));
            set(app.q4EditField,'value',r(1,4));
            set(app.q5EditField,'value',r(1,5));
            set(app.q6EditField,'value',r(1,6));
            
            ModelName = 'RobotPUMA560_3_wall';
            set_param([ModelName,'/Slider Gain'],'Gain',num2str(Q(1,1)*(180/pi)));
            set_param([ModelName,'/Slider Gain1'],'Gain',num2str(Q(1,2)*(180/pi)));
            set_param([ModelName,'/Slider Gain2'],'Gain',num2str(Q(1,3)*(180/pi)));
            set_param([ModelName,'/Slider Gain3'],'Gain',num2str(90+Q(1,4)*(180/pi)));
            set_param([ModelName,'/Slider Gain4'],'Gain',num2str(Q(1,5)*(180/pi)));
            set_param([ModelName,'/Slider Gain5'],'Gain',num2str(Q(1,6)*(180/pi)));

        end

        % Callback function
        function AnimationButtonPushed(app, event)
%             % define circle
%             import ETS3.*;
%             %mdl_puma560;
%         %% Building puma560 with DH parameter
%         l(1) = Link([0,0,0,pi/2,0]);
%         l(2) = Link([0,-0.15,0.4318,0,0]);
%         l(3) = Link([0,0,0.0203,-pi/2,0]);
%         l(4) = Link([0,0.4318,0,pi/2,0]);
%         l(5) = Link([0,0,0,-pi/2,0]);
%         l(6) = Link([0,0,0,0,0]);
%         p560 = SerialLink(l);
%             t = (0:0.2:20)'; 
%             %count = length(t);
%             center = [0.2 -0.8 0.2];radius = 0.2;%center and radius
%             theta = t*(2*pi/t(end));
%             %points =(center + radius*[cos(theta) sin(theta) zeros(size(theta))])'; %plot on x-y plane
%             points =(center + radius*[cos(theta) zeros(size(theta)) sin(theta)])'; %plot on x-z plane
%             %points=()';
%             plot3(points(1,:),points(2,:),points(3,:),'r')
%             % use Peter corke toolbox to generate trajectory 
%             hold on
%             % axis([-1 1.2 -1 1.2 -1 1.2])
%             title("trajectory generaion");
%             xlabel('x/m','FontSize',12);
%             ylabel('y/m','FontSize',12);
%             zlabel('z/m','FontSize',12);
%             for i = 1:size(points,2)
%             pause(0.01)
%             bx = points(1,i);
%             by = points(2,i);
%             bz =points(3,i);
%             tform = rpy2tr(136,-180,-180); %raw pitch yaw to homogeneous matrix
%             targetPos = [bx by bz]; %position vector
% 
%             TR=transl(targetPos)*tform; 
%             hold on
%             grid on
%             plot3(bx,by,bz,'*','LineWidth',1);
%             qq=p560.ikine6s(TR)
%             pause(0.01);
%             figure(1);
%             p560.plot(qq);% animation
%             
%             %send joint angle to simscape
%             ModelName = 'RobotPUMA560_3_wall';
%             set_param([ModelName,'/Slider Gain'],'Gain',num2str(qq(1,1)*(180/pi)));
%             set_param([ModelName,'/Slider Gain1'],'Gain',num2str(qq(1,2)*(180/pi)));
%             set_param([ModelName,'/Slider Gain2'],'Gain',num2str(-qq(1,3)*(180/pi)));
%             set_param([ModelName,'/Slider Gain3'],'Gain',num2str(90+qq(1,4)*(180/pi)));
%             set_param([ModelName,'/Slider Gain4'],'Gain',num2str(qq(1,5)*(180/pi)));
%             set_param([ModelName,'/Slider Gain5'],'Gain',num2str(qq(1,6)*(180/pi)));
%             
%         end

        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            changingValue = event.Value;
            joint1=app.Joint1Slider.Value; 
            set(app.q1EditField,'value',changingValue);
            ModelName = 'RobotPUMA560_3_wall';
            set_param([ModelName,'/Slider Gain'],'Gain',num2str(joint1));
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            changingValue = event.Value;
            joint2=app.Joint2Slider.Value;
            set(app.q2EditField,'value',changingValue);
            ModelName = 'RobotPUMA560_3_wall';
            set_param([ModelName,'/Slider Gain1'],'Gain',num2str(joint2));
            
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            changingValue = event.Value;
            joint3=app.Joint3Slider.Value;
            set(app.q3EditField,'value',changingValue);
            ModelName = 'RobotPUMA560_3_wall';
            set_param([ModelName,'/Slider Gain2'],'Gain',num2str(joint3));
        end

        % Value changing function: Joint4Slider
        function Joint4SliderValueChanging(app, event)
            changingValue = event.Value;
            joint4=app.Joint4Slider.Value;
            set(app.q4EditField,'value',changingValue);
            ModelName = 'RobotPUMA560_3_wall';
            set_param([ModelName,'/Slider Gain3'],'Gain',num2str(joint4));
        end

        % Value changing function: Joint5Slider
        function Joint5SliderValueChanging(app, event)
            changingValue = event.Value;
            joint5=app.Joint5Slider.Value+90;
            set(app.q5EditField,'value',changingValue);
            ModelName = 'RobotPUMA560_3_wall';
            set_param([ModelName,'/Slider Gain4'],'Gain',num2str(joint5));
        end

        % Value changing function: Joint6Slider
        function Joint6SliderValueChanging(app, event)
            changingValue = event.Value;
            joint6=app.Joint6Slider.Value;
            set(app.q6EditField,'value',changingValue);
            ModelName = 'RobotPUMA560_3_wall';
            set_param([ModelName,'/Slider Gain5'],'Gain',num2str(joint6));
        end

        % Value changed function: q1EditField
        function q1EditFieldValueChanged(app, event)
            value = app.q1EditField.Value;
            set(app.Joint1Slider,'value',value);
            ModelName = 'RobotPUMA560_3_wall';
            set_param([ModelName,'/Slider Gain'],'Gain',num2str(value));
        end

        % Value changed function: q2EditField
        function q2EditFieldValueChanged(app, event)
            value = app.q2EditField.Value;
            set(app.Joint2Slider,'value',value);
            ModelName = 'RobotPUMA560_3_wall';
            set_param([ModelName,'/Slider Gain1'],'Gain',num2str(value));
        end

        % Value changed function: q3EditField
        function q3EditFieldValueChanged(app, event)
            value = app.q3EditField.Value;
            set(app.Joint3Slider,'value',value);
            ModelName = 'RobotPUMA560_3_wall';
            set_param([ModelName,'/Slider Gain2'],'Gain',num2str(value));
        end

        % Value changed function: q4EditField
        function q4EditFieldValueChanged(app, event)
            value = app.q4EditField.Value;
            set(app.Joint4Slider,'value',value);
            ModelName = 'RobotPUMA560_3_wall';
            set_param([ModelName,'/Slider Gain3'],'Gain',num2str(value+90));
        end

        % Value changed function: q5EditField
        function q5EditFieldValueChanged(app, event)
            value = app.q5EditField.Value;
            set(app.Joint5Slider,'value',value);
            ModelName = 'RobotPUMA560_3_wall';
            set_param([ModelName,'/Slider Gain4'],'Gain',num2str(value));
        end

        % Value changed function: q6EditField
        function q6EditFieldValueChanged(app, event)
            value = app.q6EditField.Value;
            set(app.Joint6Slider,'value',value);
            ModelName = 'RobotPUMA560_3_wall';
            set_param([ModelName,'/Slider Gain5'],'Gain',num2str(value));
        end

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
%             value = app.Joint1Slider.Value;
        end

        % Value changed function: Joint2Slider
        function Joint2SliderValueChanged(app, event)
%             value = app.Joint2Slider.Value;
        end

        % Value changed function: Joint3Slider
        function Joint3SliderValueChanged(app, event)
%             value = app.Joint3Slider.Value;
        end

        % Value changed function: Joint4Slider
        function Joint4SliderValueChanged(app, event)
%             value = app.Joint4Slider.Value;
        end

        % Value changed function: Joint5Slider
        function Joint5SliderValueChanged(app, event)
%             value = app.Joint5Slider.Value;
        end

        % Value changed function: Joint6Slider
        function Joint6SliderValueChanged(app, event)
%             value = app.Joint6Slider.Value;
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
             ModelName = 'RobotPUMA560_3_wall';
             set_param([ModelName,'/Slider Gain'],'Gain',num2str(0));
             set_param([ModelName,'/Slider Gain1'],'Gain',num2str(0));
             set_param([ModelName,'/Slider Gain2'],'Gain',num2str(0));
             set_param([ModelName,'/Slider Gain3'],'Gain',num2str(90));
             set_param([ModelName,'/Slider Gain4'],'Gain',num2str(0));
             set_param([ModelName,'/Slider Gain5'],'Gain',num2str(0));

             set(app.q1EditField,'value',0);
             set(app.q2EditField,'value',0);
             set(app.q3EditField,'value',0);
             set(app.q4EditField,'value',0);
             set(app.q5EditField,'value',0);
             set(app.q6EditField,'value',0);
             
             set(app.Joint1Slider,'value',0);
             set(app.Joint2Slider,'value',0);
             set(app.Joint3Slider,'value',0);
             set(app.Joint4Slider,'value',0);
             set(app.Joint5Slider,'value',0);
             set(app.Joint6Slider,'value',0);
             
            q=[0,0,0,0,0,0]
            import ETS3.*;
            %mdl_puma560;
        %% Building puma560 with DH parameter
        l(1) = Link([0,0,0,pi/2,0]);
        l(2) = Link([0,-0.15,0.4318,0,0]);
        l(3) = Link([0,0,0.0203,-pi/2,0]);
        l(4) = Link([0,0.4318,0,pi/2,0]);
        l(5) = Link([0,0,0,-pi/2,0]);
        l(6) = Link([0,0,0,0,0]);
        p560 = SerialLink(l);
            figure(1);
            p560.plot(q);
        end

        % Button pushed function: FollowButton
        function FollowButtonPushed(app, event)
        import ETS3.*;
        %mdl_puma560;
        %% Building puma560 with DH parameter
        l(1) = Link([0,0,0,pi/2,0]);
        l(2) = Link([0,-0.15,0.4318,0,0]);
        l(3) = Link([0,0,0.0203,-pi/2,0]);
        l(4) = Link([0,0.4318,0,pi/2,0]);
        l(5) = Link([0,0,0,-pi/2,0]);
        l(6) = Link([0,0,0,0,0]);
        p560 = SerialLink(l);
        
        filename1='IXT.txt'; 
        filename2='IZT.txt';
        A=importdata(filename1,'%f');
        B=importdata(filename2,'%f');
        graphics=[A B];
        graf = graphics;
        s=[];
        
        
        for i=2:40:size(graf,1) %generate position information from trajectory
            Px = graf(i,1)/1200;
            Pz = graf(i,2)/1200;
            Py = -600/1200;
            Px1 = -1;
            Pz1 = graf(i,2)/1200;
            Py1 = graf(i,1)/1200;
            
            tform = rpy2tr(-90,0,0); 
            targetPos = [Px Py Pz]; 
            temp=[Px1 1.5*(0.25+Pz1) 1.5*(0.09+Py1)];
            s=cat(1,s,temp);    %trajectory is a 3XN matrix of position
            
            TR=transl(targetPos)*tform; 
            qm=p560.ikine6s(TR)
            pause(0.1);
            %send joint angle to simulink
            ModelName = 'RobotPUMA560_3_wall';
            set_param([ModelName,'/Slider Gain'],'Gain',num2str(qm(1,1)*(180/pi)));
            set_param([ModelName,'/Slider Gain1'],'Gain',num2str(qm(1,2)*(180/pi)));
            set_param([ModelName,'/Slider Gain2'],'Gain',num2str(-qm(1,3)*(180/pi)));
            set_param([ModelName,'/Slider Gain3'],'Gain',num2str(90+qm(1,4)*(180/pi)));
            set_param([ModelName,'/Slider Gain4'],'Gain',num2str(qm(1,5)*(180/pi)));
            set_param([ModelName,'/Slider Gain5'],'Gain',num2str(qm(1,6)*(180/pi)));
            
        end 
         assignin('base','eePositions',s);  %put matrix into workspace as eePositions
         assignin('base','wayPoints',[1 1 1 1]);  %put matrix into workspace as wayPoints
%         
        
        end

        % Button pushed function: DrawTrajectoryButton
        function DrawTrajectoryButtonPushed(app, event)
            MouseDraw;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InverseKinematicsLabel = uilabel(app.UIFigure);
            app.InverseKinematicsLabel.Text = {'Inverse Kinematics'; ''};
            app.pxEditFieldLabel = uilabel(app.UIFigure);
            app.pxEditFieldLabel.Text = 'px';
            app.pxEditField = uieditfield(app.UIFigure, 'numeric');
            app.pxEditField.Tag = 'px';
            app.pyEditFieldLabel = uilabel(app.UIFigure);
            app.pyEditFieldLabel.Text = 'py';
            app.pyEditField = uieditfield(app.UIFigure, 'numeric');
            app.pyEditField.Tag = 'py';
            app.pzEditFieldLabel = uilabel(app.UIFigure);
            app.pzEditFieldLabel.Text = 'pz';
            app.pzEditField = uieditfield(app.UIFigure, 'numeric');
            app.pzEditField.Tag = 'pz';
            app.q1EditFieldLabel = uilabel(app.UIFigure);
            app.q1EditFieldLabel.Text = 'q1';
            app.q1EditField = uieditfield(app.UIFigure, 'numeric');
            app.q1EditField.ValueChangedFcn = createCallbackFcn(app, @q1EditFieldValueChanged, true);
            app.q1EditField.Tag = 'theta_1';
            app.q2EditFieldLabel = uilabel(app.UIFigure);
            app.q2EditFieldLabel.Text = 'q2';
            app.q2EditField = uieditfield(app.UIFigure, 'numeric');
            app.q2EditField.ValueChangedFcn = createCallbackFcn(app, @q2EditFieldValueChanged, true);
            app.q2EditField.Tag = 'theta_2';
            app.q3EditFieldLabel = uilabel(app.UIFigure);
            app.q3EditFieldLabel.Text = 'q3';
            app.q3EditField = uieditfield(app.UIFigure, 'numeric');
            app.q3EditField.ValueChangedFcn = createCallbackFcn(app, @q3EditFieldValueChanged, true);
            app.q3EditField.Tag = 'theta_3';
            app.FowardKinematicsLabel = uilabel(app.UIFigure);
            app.FowardKinematicsLabel.Text = {'Foward Kinematics'; ''};
            app.q4EditFieldLabel = uilabel(app.UIFigure);
            app.q4EditFieldLabel.Text = 'q4';
            app.q4EditField = uieditfield(app.UIFigure, 'numeric');
            app.q4EditField.ValueChangedFcn = createCallbackFcn(app, @q4EditFieldValueChanged, true);
            app.q4EditField.Tag = 'theta_4';
            app.q5EditFieldLabel = uilabel(app.UIFigure);
            app.q5EditFieldLabel.Text = 'q5';
            app.q5EditField = uieditfield(app.UIFigure, 'numeric');
            app.q5EditField.ValueChangedFcn = createCallbackFcn(app, @q5EditFieldValueChanged, true);
            app.q5EditField.Tag = 'theta_5';
            app.q6EditFieldLabel = uilabel(app.UIFigure);
            app.q6EditFieldLabel.Text = 'q6';
            app.q6EditField = uieditfield(app.UIFigure, 'numeric');
            app.q6EditField.ValueChangedFcn = createCallbackFcn(app, @q6EditFieldValueChanged, true);
            app.q6EditField.Tag = 'theta_6';
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Tag = 'forward';
            app.ForwardButton.Text = 'Forward';
            app.InverseButton = uibutton(app.UIFigure, 'push');
            app.InverseButton.ButtonPushedFcn = createCallbackFcn(app, @InverseButtonPushed, true);
            app.InverseButton.Tag = 'inverse';
            app.InverseButton.Text = 'Inverse';
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Limits = [-180 180];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.Limits = [-45 225];
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderValueChanged, true);
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.Limits = [-225 45];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderValueChanged, true);
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.Joint4SliderLabel = uilabel(app.UIFigure);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.UIFigure);
            app.Joint4Slider.Limits = [-110 180];
            app.Joint4Slider.ValueChangedFcn = createCallbackFcn(app, @Joint4SliderValueChanged, true);
            app.Joint4Slider.ValueChangingFcn = createCallbackFcn(app, @Joint4SliderValueChanging, true);
            app.Joint5SliderLabel = uilabel(app.UIFigure);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.UIFigure);
            app.Joint5Slider.Limits = [-100 100];
            app.Joint5Slider.ValueChangedFcn = createCallbackFcn(app, @Joint5SliderValueChanged, true);
            app.Joint5Slider.ValueChangingFcn = createCallbackFcn(app, @Joint5SliderValueChanging, true);
            app.Joint6SliderLabel = uilabel(app.UIFigure);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.UIFigure);
            app.Joint6Slider.Limits = [-266 266];
            app.Joint6Slider.ValueChangedFcn = createCallbackFcn(app, @Joint6SliderValueChanged, true);
            app.Joint6Slider.ValueChangingFcn = createCallbackFcn(app, @Joint6SliderValueChanging, true);
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Tag = 'animation';
            app.HomeButton.Text = {'Home'; ''};
            app.FollowButton = uibutton(app.UIFigure, 'push');
            app.FollowButton.ButtonPushedFcn = createCallbackFcn(app, @FollowButtonPushed, true);
            app.FollowButton.Tag = 'animation';
            app.FollowButton.Text = 'Follow ';
            app.DrawTrajectoryButton = uibutton(app.UIFigure, 'push');
            app.DrawTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @DrawTrajectoryButtonPushed, true);
            app.DrawTrajectoryButton.Tag = 'animation';
            app.DrawTrajectoryButton.Text = 'Draw Trajectory';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end